classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AudioAnalyzerStreamlinedAudioFilteringandAnalysisLabel  matlab.ui.control.Label
        DenoisingthenegativeFactorsLabel  matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        TypeSwitch                   matlab.ui.control.Switch
        TypeSwitchLabel              matlab.ui.control.Label
        SetParametersButton          matlab.ui.control.Button
        CuttOffFrequencySlider       matlab.ui.control.Slider
        CuttOffFrequencySliderLabel  matlab.ui.control.Label
        OrderSlider                  matlab.ui.control.Slider
        OrderSliderLabel             matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Panel_2                      matlab.ui.container.Panel
        SaveAudioCheckBox            matlab.ui.control.CheckBox
        DenoisingLabel               matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        FilteredAudioButton          matlab.ui.control.Button
        PlayAudioButton              matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        BrowseButton                 matlab.ui.control.Button
        AudioFileEditField           matlab.ui.control.EditField
        AudioFileLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        audioFile % Path to the audio file
        Fs % Sampling rate
        y % Audio data
        order % Filter order
        cutoff_freq % Cutoff frequency
        filterType % Filter type (low-pass or high-pass)
        saveAudio % Flag to save audio
        player % Audioplayer object
    end
 
    methods (Access = private)
          function plotAudio(app, y, titleText, axes)
            t = (0:length(y)-1) / app.Fs;
            plot(axes, t, y,'g');
            title(axes, titleText);
            xlabel(axes, 'Time (s)');
            ylabel(axes, 'Amplitude');
            grid(axes, 'on');
          end

          function plotFrequencySpectrum(app, y, titleText, axes)
            N = length(y);
            f = (-app.Fs/2):(app.Fs/N):(app.Fs/2 - app.Fs/N);
            Y = fftshift(fft(y));
            plot(axes, f, abs(Y),'r');
            title(axes, titleText);
            xlabel(axes, 'Frequency (Hz)');
            ylabel(axes, 'Magnitude');
            grid(axes, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, path] = uigetfile({'*.wav', 'WAV files (*.wav)'; '*.mp3', 'MP3 files (*.mp3)'; '*.flac', 'FLAC files (*.flac)'}, 'Select an Audio File');
            if filename ~= 0
                app.audioFile = fullfile(path, filename);
                app.AudioFileEditField.Value = app.audioFile;
                [app.y, app.Fs] = audioread(app.audioFile);
            end
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
           app.order = round(app.OrderSlider.Value);
           app.cutoff_freq = app.CuttOffFrequencySlider.Value; 
            app.filterType = app.TypeSwitch.Value; % Set filter type
            app.saveAudio = app.SaveAudioCheckBox.Value; % Set save audio flag
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if ~isempty(app.audioFile)
                 if isempty(app.player) || ~isplaying(app.player)
                    app.player = audioplayer(app.y, app.Fs);
                    play(app.player);
                end
                
                app.plotAudio(app.y, 'Time Domain Analysis - Original Audio', app.UIAxes);
                app.plotFrequencySpectrum(app.y, 'Frequency Domain Analysis - Original Audio', app.UIAxes2);
            end
        end

        % Button pushed function: FilteredAudioButton
        function FilteredAudioButtonPushed(app, event)
         
          if ~isempty(app.audioFile) && ~isempty(app.order) && ~isempty(app.cutoff_freq)
                if strcmp(app.filterType, 'Low')
                    [b, a] = butter(app.order, app.cutoff_freq / (app.Fs / 2), 'low');
                else
                    [b, a] = butter(app.order, app.cutoff_freq / (app.Fs / 2), 'high');
                end
                y_filtered = filter(b, a, app.y);
                if isempty(app.player) || ~isplaying(app.player)
                    app.player = audioplayer(y_filtered, app.Fs);
                    play(app.player);
                end
                app.plotAudio(y_filtered, 'Time Domain Analysis - Filtered Audio', app.UIAxes);
                app.plotFrequencySpectrum(y_filtered, 'Frequency Domain Analysis - Filtered Audio', app.UIAxes2);

                if app.saveAudio
                    [file,path] = uiputfile('filtered_audio.wav','Save Filtered Audio As');
                    if ischar(file)
                        audiowrite(fullfile(path, file), y_filtered, app.Fs);
                    end
                end
          end
         
        end

        % Value changed function: TypeSwitch
        function TypeSwitchValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'Address';
            app.Panel = uipanel(app.UIFigure);
            app.AudioFileLabel = uilabel(app.Panel);
            app.AudioFileLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.Panel, 'text');
            app.AudioFileEditField.Tag = 'AudioFile';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Panel_2 = uipanel(app.UIFigure);
            app.PlayAudioButton = uibutton(app.Panel_2, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Tag = 'original_audio';
            app.PlayAudioButton.Icon = 'pngegg.png';
            app.PlayAudioButton.Text = 'Play Audio';
            app.FilteredAudioButton = uibutton(app.Panel_2, 'push');
            app.FilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredAudioButtonPushed, true);
            app.FilteredAudioButton.Tag = 'Filtered_audio';
            app.FilteredAudioButton.Icon = 'pngegg.png';
            app.FilteredAudioButton.Text = 'Filtered Audio';
            app.Image_2 = uiimage(app.Panel_2);
            app.Image_2.ImageSource = 'music.png';
            app.DenoisingLabel = uilabel(app.Panel_2);
            app.DenoisingLabel.Text = 'Denoising';
            app.SaveAudioCheckBox = uicheckbox(app.Panel_2);
            app.SaveAudioCheckBox.Tag = 'Save audio';
            app.SaveAudioCheckBox.Text = 'Save Audio';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Time Domain Analysis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'Time_Graph';
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'Frequency Domain Analysis')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'Frequency_Graph';
            app.Panel_4 = uipanel(app.UIFigure);
            app.OrderSliderLabel = uilabel(app.Panel_4);
            app.OrderSliderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.Panel_4);
            app.OrderSlider.Limits = [0 20];
            app.OrderSlider.MajorTicks = [0 5 10 15 20];
            app.OrderSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.OrderSlider.Tag = 'Order';
            app.CuttOffFrequencySliderLabel = uilabel(app.Panel_4);
            app.CuttOffFrequencySliderLabel.Text = 'CuttOff Frequency';
            app.CuttOffFrequencySlider = uislider(app.Panel_4);
            app.CuttOffFrequencySlider.Limits = [0 5000];
            app.CuttOffFrequencySlider.MajorTicks = [0 1000 2000 3000 4000 5000];
            app.CuttOffFrequencySlider.MinorTicks = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000 3250 3500 3750 4000 4250 4500 4750 5000];
            app.CuttOffFrequencySlider.Tag = 'Cutoff';
            app.SetParametersButton = uibutton(app.Panel_4, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Tag = 'Set_parameters';
            app.SetParametersButton.Text = 'Set Parameters';
            app.TypeSwitchLabel = uilabel(app.Panel_4);
            app.TypeSwitchLabel.Text = 'Type';
            app.TypeSwitch = uiswitch(app.Panel_4, 'slider');
            app.TypeSwitch.Items = {'Low', 'High'};
            app.TypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeSwitchValueChanged, true);
            app.TypeSwitch.Tag = 'type';
            app.TypeSwitch.Value = 'Low';
            app.DenoisingthenegativeFactorsLabel = uilabel(app.UIFigure);
            app.DenoisingthenegativeFactorsLabel.Text = 'Denoising the negative Factors';
            app.AudioAnalyzerStreamlinedAudioFilteringandAnalysisLabel = uilabel(app.UIFigure);
            app.AudioAnalyzerStreamlinedAudioFilteringandAnalysisLabel.Text = 'Audio Analyzer: Streamlined Audio Filtering and Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end